classdef Zadanie3App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        loha1Tab                       matlab.ui.container.Tab
        OtvoritprieinoksvsledkamiButton  matlab.ui.control.Button
        OtvoritsborsvsledkamiButton    matlab.ui.control.Button
        Results                        matlab.ui.control.ListBox
        PomerfariebEditField_2         matlab.ui.control.EditField
        PomerfariebEditField_2Label    matlab.ui.control.Label
        PomerfariebzadvajteakocelslaoddelenmedzerouLabel_2  matlab.ui.control.Label
        AktualizovaButton              matlab.ui.control.Button
        PomerfariebzadvajteakocelslaoddelenmedzerouLabel  matlab.ui.control.Label
        PomerfariebEditField           matlab.ui.control.EditField
        PomerfariebEditFieldLabel      matlab.ui.control.Label
        ZtohojeListBox                 matlab.ui.control.ListBox
        ZtohojeListBoxLabel            matlab.ui.control.Label
        VsledkyLabel                   matlab.ui.control.Label
        NastavihodnotyzozadaniaButton  matlab.ui.control.Button
        VypotapravdepodobnosButton     matlab.ui.control.Button
        numberOfPicked                 matlab.ui.control.Spinner
        VytiahnemeksLabel              matlab.ui.control.Label
        VytiahnutgulikybudButtonGroup  matlab.ui.container.ButtonGroup
        rzneButton                     matlab.ui.control.RadioButton
        rovnakButton                   matlab.ui.control.RadioButton
        numberOfColors                 matlab.ui.control.Spinner
        PoetfariebguliiekLabel         matlab.ui.control.Label
        numberOfBalls                  matlab.ui.control.Spinner
        PoetguliiekvurneLabel          matlab.ui.control.Label
        ZadanieLabel                   matlab.ui.control.Label
        loha2Tab                       matlab.ui.container.Tab
        results2                       matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        OtvoritprieinoksvsledkamiButton_2  matlab.ui.control.Button
        OtvoritsborsvsledkamiButton_2  matlab.ui.control.Button
        VsledkyLabel_2                 matlab.ui.control.Label
        NastavihodnotyzozadaniaButton_2  matlab.ui.control.Button
        VypotaminimlnypoethodovButton  matlab.ui.control.Button
        numberOfSite3                  matlab.ui.control.Spinner
        Poetpadnutstranyslo3Label      matlab.ui.control.Label
        numberOfRolls                  matlab.ui.control.Spinner
        PoethodovprvoukockouLabel      matlab.ui.control.Label
        second                         matlab.ui.control.DropDown
        KockaktorousahdedruhouLabel    matlab.ui.control.Label
        KockaktorousahdeprvouLabel     matlab.ui.control.Label
        ZadanieLabel_2                 matlab.ui.control.Label
        first                          matlab.ui.control.DropDown
        loha3Tab                       matlab.ui.container.Tab
        results3                       matlab.ui.control.TextArea
        OtvoritprieinoksvsledkamiButton_3  matlab.ui.control.Button
        OtvoritsborsvsledkamiButton_3  matlab.ui.control.Button
        VsledkyLabel_3                 matlab.ui.control.Label
        Vstupndta3Label                matlab.ui.control.Label
        Spustianalzudt3Button          matlab.ui.control.Button
        bottomLine3                    matlab.ui.control.Spinner
        DolnhranicaprvejtriedyLabel_3  matlab.ui.control.Label
        PoettriedpoetnostiLabel_3      matlab.ui.control.Label
        numberOfClasses3               matlab.ui.control.Spinner
        Vstupndta2Label                matlab.ui.control.Label
        Spustianalzudt2Button          matlab.ui.control.Button
        bottomLine2                    matlab.ui.control.Spinner
        DolnhranicaprvejtriedyLabel_2  matlab.ui.control.Label
        PoettriedpoetnostiLabel_2      matlab.ui.control.Label
        numberOfClasses2               matlab.ui.control.Spinner
        Vstupndta1Label                matlab.ui.control.Label
        Spustianalzudt1Button          matlab.ui.control.Button
        NastavihodnotyzozadaniaButton_3  matlab.ui.control.Button
        SpustianalzuvtekchdtButton     matlab.ui.control.Button
        bottomLine1                    matlab.ui.control.Spinner
        DolnhranicaprvejtriedyLabel    matlab.ui.control.Label
        PoettriedpoetnostiLabel        matlab.ui.control.Label
        numberOfClasses1               matlab.ui.control.Spinner
        VybraButton                    matlab.ui.control.Button
        VstupnsborLabel                matlab.ui.control.Label
        fileName                       matlab.ui.control.EditField
        ZadanieLabel_3                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        BallsPicked
        NumberOfPickedBalls
        NumberOfAllBalls
        NumberOfColors
        BallsInPack
        BallsPickedColors
        pravdepodobnostRovnake
        pravdepodobnostRozne

        firstValue
        secondValue
        allRolls
        successRolls
        pravdepodobnostPrva
        pocetHodov

        selectedFile
        selectedPath
        Data1
        Data2
        Data3

        AllResults
        TabulatedData
        I
        ai
        bi
        xi
        ni
        N
        fi
        F
    end
        
    methods (Access = private)
        
        % nastavenie radio button menu na vyber toho ci sa vyberaju orvnake
        % alebo rozne gulicky na rozne
        function setPickedToRozne(app)
            app.rzneButton.Value = true;
            app.rovnakButton.Value = false;
        end

        % nastavenie radio button menu na vyber toho ci sa vyberaju orvnake
        % alebo rozne gulicky na rovnake
        function setPickedToRovnake(app)
            app.rzneButton.Value = false;
            app.rovnakButton.Value = true;
        end

        % ulozenie vypocitanych hodnot aj s komentarom do suboru
        function storeResultsForSamePick(app)
            pickedBallsCount = app.NumberOfPickedBalls;
            allColorsCount = app.NumberOfColors;

            if ~exist('OutputFiles', 'dir')
               mkdir OutputFiles
            end

            NameOfFile = ('Pravdepodobnost1.txt'); % nazov suboru
            Fpath1 = fullfile('OutputFiles',NameOfFile); % cesta k nemu
            fileID1 = fopen(Fpath1,'w','n','UTF-8'); % otvorneie na pridavanie

            fprintf(fileID1,'Vstupne hodnoty: \r\n');
            fprintf(fileID1,'----------------- \r\n');
            fprintf(fileID1,'Pocet vsetkych guliciek: %d\r\n', app.NumberOfAllBalls);
            fprintf(fileID1,'Pocet roznych farieb guliciek: %d\r\n', app.NumberOfColors);

            fprintf(fileID1,'Pocet guliciek konkretnych farieb\r\n');
            for i=1:app.NumberOfColors
                text = ['Farba ', num2str(i), ': ', num2str(app.BallsInPack(i)), 'ks'];
                fprintf(fileID1,'\t%s\r\n', text);
            end

            fprintf(fileID1,'Pocet vybratych guliciek: %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1,'Vytiahnute gulicky su: rovnake\r\n\r\n');
            
            fprintf(fileID1,'VZOREC: \r\n');
            fprintf(fileID1,'-------- \r\n');
            fprintf(fileID1, 'r - kolko je sposobov na vytiehnutie %d guliek z urny (ked nam nezalezi na farbe tahanych)\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'r = nchoosek(allBallsCount, pickedBallsCount)\r\n\r\n');
            fprintf(fileID1, 'm - kolko je sposobov aby sme spomedzi vsetkych guliek jednej farby vybrali prave %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'm = nchoosek(numberOfThisColor, pickedBallsCount)\r\n\r\n');

            fprintf(fileID1, 'P = m / r\r\n\r\n');

            fprintf(fileID1,'VYSLEDOK: \r\n');
            fprintf(fileID1,'---------- \r\n');
            fprintf(fileID1,'Pravdepodobnost vybratia %s guliciek farby \r\n', num2str(pickedBallsCount));
            for i=1:allColorsCount
                if app.pravdepodobnostRovnake(i) == -1
                    text = ['F', num2str(i), ' sa neda vypocitat nakolko chceme vytiahnut viac guliciek ako je z tej farby v urne.'];
                else
                    text = ['F', num2str(i), ' je: ', num2str(app.pravdepodobnostRovnake(i))];
                end
                fprintf(fileID1,'\t%s\r\n', text);
            end

            fclose(fileID1);
        end
        
        function storeResultsForDifferentPick(app)
            pickedBallsCount = app.NumberOfPickedBalls;
            allColorsCount = app.NumberOfColors;

            if ~exist('OutputFiles', 'dir')
               mkdir OutputFiles
            end

            NameOfFile = ('Pravdepodobnost1.txt'); % nazov suboru
            Fpath1 = fullfile('OutputFiles',NameOfFile); % cesta k nemu
            fileID1 = fopen(Fpath1,'w','n','UTF-8'); % otvorneie na pridavanie

            fprintf(fileID1,'Vstupne hodnoty: \r\n');
            fprintf(fileID1,'----------------- \r\n');
            fprintf(fileID1,'Pocet vsetkych guliciek: %d\r\n', app.NumberOfAllBalls);
            fprintf(fileID1,'Pocet roznych farieb guliciek: %d\r\n', app.NumberOfColors);

            fprintf(fileID1,'Pocet guliciek konkretnych farieb\r\n');
            for i=1:app.NumberOfColors
                text = ['Farba ', num2str(i), ': ', num2str(app.BallsInPack(i)), 'ks'];
                fprintf(fileID1,'\t%s\r\n', text);
            end

            fprintf(fileID1,'Pocet vybratych guliciek: %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1,'Vytiahnute gulicky su: rozne\r\n');
            fprintf(fileID1,'Pomer farieb vytiahnutych guliciek\r\n\r\n');
            for i=1:app.NumberOfColors
                text = ['Farba ', num2str(i), ': ', num2str(app.BallsPickedColors(i)), 'ks'];
                fprintf(fileID1,'\t%s\r\n', text);
            end
            
            fprintf(fileID1,'VZOREC: \r\n');
            fprintf(fileID1,'-------- \r\n');
            fprintf(fileID1, 'r - kolko je sposobov na vytiehnutie %d guliek z urny (ked nam nezalezi na farbe tahanych)\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'r = nchoosek(allBallsCount, pickedBallsCount)\r\n\r\n');
            fprintf(fileID1, 'm - kolkymi sposobmi sa da dosiahnut pozadovany pomer vytiahnutych farieb %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'm = nchoosek(n1, k1) * nchoosek(n2, k2) * ... * nchoosek(nx, kx)\r\n');
            fprintf(fileID1, '    pre x = <1; pocet vsetkych farieb>, kde nx je pocet guliciek danej farby v urne a kx pocet guliciek danej farby ktore sa vyberu\r\n\r\n');

            fprintf(fileID1, 'P = m / r\r\n\r\n');

            fprintf(fileID1,'VYSLEDOK: \r\n');
            fprintf(fileID1,'---------- \r\n');
            fprintf(fileID1,'Pravdepodobnost vybratia %s guliciek vo vyssie uvedenom pomere je %s \r\n', num2str(pickedBallsCount), num2str(app.pravdepodobnostRozne));
            fclose(fileID1);
        end

        % vypocet pravdepodobnosti v pripade ze je zvolena moznost rovnake
        % gulicky
        function pickedSameBalls(app)
            allBallsCount = app.NumberOfAllBalls;
            pickedBallsCount = app.NumberOfPickedBalls;
            allColorsCount = app.NumberOfColors;
            app.pravdepodobnostRovnake = [];
            r = nchoosek(allBallsCount, pickedBallsCount); % kolkymi moznostami vieme vybrat urceny pocet guliciek ked neriesime farbu
            
            for i=1:allColorsCount
                numberOfThisColor = app.BallsInPack(i);
                if pickedBallsCount > numberOfThisColor
                    P = -1;
                else
                    m = nchoosek(numberOfThisColor, pickedBallsCount);
                    P = m / r;
                end
                app.pravdepodobnostRovnake(end+1) = P;
            end

            app.Results.Items = {};
            drawnow

            for i=1:allColorsCount
                if app.pravdepodobnostRovnake(i) == -1
                    name = ['Pravdepodobnost vybratia ', num2str(pickedBallsCount), ' guliciek farby ', num2str(i), ' sa nedá vypočítať nakoľko chceme vytiahnuť viac guličiek ako je z tej farby v urne'];
                else
                    name = ['Pravdepodobnost vybratia ', num2str(pickedBallsCount), ' guliciek farby ', num2str(i), ' je: ', num2str(app.pravdepodobnostRovnake(i))];
                end
                app.Results.Items(end+1) = {name};
                drawnow
            end
            storeResultsForSamePick(app);
        end
        
        % vypocet pravdepodobnosti v pripade ze je zvolena moznost rozne
        % gulicky
        function pickedDifferentBalls(app)
            % rozparsovanie zadaneho pomeru farieb ktore sa vyberu
            values = app.PomerfariebEditField_2.Value;
            splitedValues = strsplit(values);

            [~,numberOfValues] = size(splitedValues);
            
            app.BallsPickedColors = [];
            
            newBallCount = 0;
            inputBallCount = app.NumberOfPickedBalls;

            colorsCount = app.numberOfColors.Value;

            for i = 1:min(numberOfValues, colorsCount)
                currentValue = str2double(splitedValues(i));
                if isnan(currentValue)
                    uialert(app.UIFigure,'Medzi zadanými hodnotami sa nachádzajú aj iné znaky ako čísla, prípadne medzera na konci navyše','Chyba');
                    return
                end
                app.BallsPickedColors(end+1) = currentValue;
                newBallCount = newBallCount + currentValue;
            end

            if newBallCount < inputBallCount && numberOfValues >= colorsCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je menší ako počet vybraných guličiek.','Chyba');
                return
            end 

            if newBallCount > inputBallCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je väčší ako počet vybraných guličiek.','Chyba');
                return
            end

            if numberOfValues > colorsCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je vyšší ako počet farieb. Hodnoty navyše budú zahodené','Varovanie', 'Icon', 'warning');
            end

            countOneColor = 0;
            m = 0;

            if numberOfValues < colorsCount && newBallCount ~= inputBallCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je menší ako počet farieb. Zvyšný diel bude rovnomerne rozdelený medzi zvyšné farby','Varovanie', 'Icon', 'warning');
                
                missingBalls = inputBallCount - newBallCount;
                missingColors = colorsCount - numberOfValues;
                countOneColor = floor(missingBalls / missingColors);
                m = mod(missingBalls, missingColors);
            end
            
            middleStop = min(numberOfValues, colorsCount) + 1;
            for i=middleStop:colorsCount
                if i == middleStop
                    app.BallsPickedColors(end+1) = m + countOneColor;
                else
                    app.BallsPickedColors(end+1) = countOneColor;
                end

            end

            % vypocet pravdepodobnosti
            allBallsCount = app.NumberOfAllBalls;
            pickedBallsCount = app.NumberOfPickedBalls;

            allColorsCount = app.NumberOfColors;

            r = nchoosek(allBallsCount, pickedBallsCount); % kolkymi moznostami vieme vybrat urceny pocet guliciek ked neriesime farbu
            
            % vypocet kolkymi sposobmi vie vybrat pozadovany pomer farieb
            m = 1;
            disp(app.BallsPickedColors);
            for i=1:allColorsCount
                numberOfThisColor = app.BallsInPack(i);
                numberOfPickedOfThisColor = app.BallsPickedColors(i);

                if numberOfPickedOfThisColor > numberOfThisColor
                    uialert(app.UIFigure, ['Počet guličiek ktoré chceme vybrať z danej farby je vyšší ako celkový počet guličiek. (Farba číslo ', num2str(i), ')'], 'Chyba pri farbe ');
                    return
                end
                m = m * nchoosek(numberOfThisColor, numberOfPickedOfThisColor);
            end

            P = m / r;
            app.pravdepodobnostRozne = P;

            app.Results.Items = {};
            drawnow

            name = ['Pravdepodobnost vybratia ', num2str(pickedBallsCount), ' guliciek v pomere:'];
            app.Results.Items(end+1) = {name};
            for i=1:allColorsCount
                name = ['    Farba ', num2str(i), ': ', num2str(app.BallsPickedColors(i)), ' ks'];
                app.Results.Items(end+1) = {name};
                drawnow
            end

            name = ['je: ', num2str(P)];
            app.Results.Items(end+1) = {name};

            storeResultsForDifferentPick(app);
        end
        
        % na zistenie kolko je ktorych farieb v urne
        function getBallsInPack(app)
            app.BallsInPack = [];
            listOfBalls = app.ZtohojeListBox.Items;
            for i=1:app.NumberOfColors 
                oneLine = split(listOfBalls(i));
                app.BallsInPack(end+1) = str2double(oneLine(3));
            end
        end
      
        % updatuje listbox s tym kolko je ktorych farieb v urne
        function updateListboxWithColors(app)
            app.ZtohojeListBox.Items = {};
            drawnow
            
            countBalls = app.numberOfBalls.Value;
            countColors = app.numberOfColors.Value;
            countOneColor = floor(countBalls / countColors);
            m = mod(countBalls, countColors);
            for i=1:countColors
                countString = num2str(countOneColor);
                if i == 1
                    countString = num2str(countOneColor + m);
                end
                name = ['farba ', num2str(i), ': ', countString, ' ks'];
                app.ZtohojeListBox.Items(end+1) = {name};
                drawnow
            end
        end
    
        % vypocet pravdepodobnosti pre hod prvym telesom
        function probabilityFirst(app)
            p = 1 / app.firstValue;
            n = app.allRolls;
            k = app.successRolls;
            disp(['k: ', num2str(k), ' n: ', num2str(n)]);
            P = nchoosek(n, k) * (p ^ k) * ((1-p)^(n-k));
            app.pravdepodobnostPrva = P;
        end
        
        % zistovanie kolkokrat treba hodit druhym
        function countRolls(app)
            p = 1 / app.secondValue;

            k = app.successRolls;
            n = k;
            P = nchoosek(n, k) * (p ^ k) * ((1-p)^(n-k));
            
            while P < app.pravdepodobnostPrva
                n = n + 1;
                k = app.successRolls;
                P = nchoosek(n, k) * (p ^ k) * ((1-p)^(n-k));
            end

            app.pocetHodov = n;
        end
    
        function storeResults2(app)
            if ~exist('OutputFiles', 'dir')
               mkdir OutputFiles
            end

            NameOfFile = ('Pravdepodobnost2.txt'); % nazov suboru
            Fpath1 = fullfile('OutputFiles',NameOfFile); % cesta k nemu
            fileID2 = fopen(Fpath1,'w','n','UTF-8'); % otvorneie na pridavanie

            fprintf(fileID2,'Vstupne hodnoty: \r\n');
            fprintf(fileID2,'----------------- \r\n');
            fprintf(fileID2, 'Prve teleso ktorym sa hadze je %d-sten\r\n', app.firstValue);
            fprintf(fileID2, 'Druhe teleso ktorym sa hadze je %d-sten\r\n', app.secondValue);

            fprintf(fileID2, 'Prvym telesom sme hodili %d-krat\r\n', app.allRolls);
            fprintf(fileID2, 'Strana s cislo 3 spadla %d-krat\r\n\r\n', app.successRolls);

            fprintf(fileID2,'VZOREC: \r\n');
            fprintf(fileID2,'-------- \r\n');
            
            fprintf(fileID2,'VYSLEDOK: \r\n');
            fprintf(fileID2,'---------- \r\n');
            fprintf(fileID2, 'Druhym telesom musime hodit aspon %d-krat aby pravdepodobnost toho ze cislo 3 spadne %d-krat bola vacsia ako v pokuse s prvym telesom.\r\n', app.pocetHodov, app.successRolls);

            fclose(fileID2);
        end
        
        function loadMetaData(app)
            Fpath = fullfile(app.selectedPath, app.selectedFile);

            %nacitanie harkov
            sheets = sheetnames(Fpath);
            sheet1 = sheets(2);
            sheet2 = sheets(3);
            sheet3 = sheets(4);
            
            DataRange1 = 'B4:L24';
            DataRange2 = 'B4:Q34';
            DataRange3 = 'B4:BE164';

            data = xlsread(Fpath,sheet1,DataRange1);
            dataSorted = sort(data(:));
            app.Data1 = rmmissing(dataSorted);

            data = xlsread(Fpath,sheet2,DataRange2);
            dataSorted = sort(data(:));
            app.Data2 = rmmissing(dataSorted);

            data = xlsread(Fpath,sheet3,DataRange3);
            dataSorted = sort(data(:));
            app.Data3 = rmmissing(dataSorted);

            setInputValues(app);
        end

        function setInputValues(app)
            n = length(app.Data1);
            if n <= 30
                app.numberOfClasses1.Enable = false;
                app.bottomLine1.Enable = false;
            else
                app.numberOfClasses1.Limits = [1, n];
                if n > 900
                    app.numberOfClasses1.Value = round(1 + 3.332 * log(n));
                else
                    app.numberOfClasses1.Value = round(sqrt(n));
                end
                app.bottomLine1.Limits = [min(app.Data1, [], 'all'), max(app.Data1, [], 'all')];
                app.bottomLine1.Value = min(app.Data1, [], 'all');
            end
            
            n = length(app.Data2);
            if n <= 30
                app.numberOfClasses2.Enable = false;
                app.bottomLine2.Enable = false;
            else
                app.numberOfClasses2.Limits = [1, n];
                if n > 900
                    app.numberOfClasses2.Value = round(1 + 3.332 * log(n));
                else
                    app.numberOfClasses2.Value = round(sqrt(n));
                end
                app.bottomLine2.Limits = [min(app.Data2, [], 'all'), max(app.Data2, [], 'all')];
                app.bottomLine2.Value = min(app.Data2, [], 'all');
            end
            
            n = length(app.Data3);
            if n <= 30
                app.numberOfClasses3.Enable = false;
                app.bottomLine3.Enable = false;
            else
                app.numberOfClasses3.Limits = [1, n];
                if n > 900
                    app.numberOfClasses3.Value = round(1 + 3.332 * log(n));
                else
                    app.numberOfClasses3.Value = round(sqrt(n));
                end
                app.bottomLine3.Limits = [min(app.Data3, [], 'all'), max(app.Data3, [], 'all')];
                app.bottomLine3.Value = min(app.Data3, [], 'all');
                disp(app.numberOfClasses3.Limits);
            end
        end
    
        function calculateUnder30(app, data, id)
            n = length(data);
            r = {};

            priemer = mean(data, 'all');
            r(end + 1) = {['Aritmetický priemer:_', num2str(priemer)]};
            
            modus = mode(data, 'all');
            r(end + 1) = {['Modus:_', num2str(modus)]};

            med = median(data, 'all');
            r(end + 1) = {['Medián:_', num2str(med)]};

            variacne = range(data, 'all');
            r(end + 1) = {['Variačné rozpätie:_', num2str(variacne)]};

            priemerna = (1/n)*sum(abs(data - priemer));
            r(end + 1) = {['Priemerná odchýlka:_', num2str(priemerna)]};

            rozptyl = (1/n)*sum(abs(data - priemer).^2);
            r(end + 1) = {['Rozptyl:_', num2str(rozptyl)]};

            smerodajna = sqrt(rozptyl);
            r(end + 1) = {['Smerodajná odchýlka:_', num2str(smerodajna)]};

            Mi3 = (1/n)*sum((data - priemer).^3);
            Mi4 = (1/n)*sum((data - priemer).^4);
            
            gama3 = Mi3/smerodajna^3;
            r(end + 1) = {['Koeficient šikmosti:_', num2str(gama3)]};
            
            gama4 = (Mi4/smerodajna^4)-3;
            r(end + 1) = {['Koeficient špicatosti:_', num2str(gama4)]};

            app.AllResults = r;

            app.I = [];
            app.ai = [];
            app.bi = [];
            app.xi = [];
            app.ni = [];
            app.N = [];
            app.fi = [];
            app.F = [];
            
            app.I(end+1) = 1;
            app.ai(end+1) = min(data, [], 'all');
            app.bi(end+1) = max(data, [], 'all');
            app.xi(end+1) = 1/2*(app.ai + app.bi);
            customTabulate(app, data);
            app.ni(end+1) = length(app.TabulatedData);
            app.N(end+1) = sum(app.ni);
            app.fi(end+1) = app.ni/length(data);
            app.F(end+1) = sum(app.fi);

            storeResults3(app, id);
        end
        
        function customTabulate(app, data)
            D = tabulate(data);
            [r, ~] = size(D);
            tabulated = [];
            for i=1:r
                if D(i, 2) ~= 0
                    tabulated = [tabulated; D(i,1)];
                end
            end

            app.TabulatedData = tabulated;
        end

        function calculateOver30(app, data, id)
            if id == 1
                pocetTried = app.numberOfClasses1.Value;
                start = app.bottomLine1.Value;
            elseif id == 2
                pocetTried = app.numberOfClasses2.Value;
                start = app.bottomLine2.Value;
            elseif id == 3
                pocetTried = app.numberOfClasses3.Value;
                start = app.bottomLine3.Value;
            end

            app.I = [];
            app.ai = [];
            app.bi = [];
            app.xi = [];
            app.ni = [];
            app.N = [];
            app.fi = [];
            app.F = [];

            n = length(data);
            disp(n);
            disp(pocetTried);
            R = max(data) - min(data);
            h1 = R/pocetTried;
            decimals = 2;
            h = ceil(h1*10^decimals)/(10^decimals);
            
            app.I = 1:pocetTried;
            a = [start:h:start+((pocetTried-1)*h)]';
            b = a + h;
            x = (a+b)/2;
            app.ai = a;
            app.bi = b;
            app.xi = x;
            
            app.N = zeros(pocetTried,1);
            for i = 1:pocetTried
                for j = 1:n
                    if(data(j) <= app.bi(i))
                        app.N(i) = app.N(i) + 1;
                    end
                end
            end
            
            app.ni = zeros(pocetTried,1);
            app.ni(1) = app.N(1);
            for i = 2:pocetTried
                app.ni(i) = app.N(i) - app.N(i-1);
            end
            
            app.fi = zeros(pocetTried,1);
            app.fi = app.ni./n;
            
            app.F = zeros(pocetTried,1);
            app.F = app.N./n;

            app.AllResults = [];

            priemer = (1/n)*app.ni'*x;
            
            % modus
            [~,index] = max(app.ni);
            d1 = app.ni(index) - app.ni(index-1);
            d2 = app.ni(index) - app.ni(index+1);
            modus = a(index) + h*(d1/(d1+d2));
            
            %Median
            index = 0;
            for i = 1:pocetTried
                if(app.N(i) > n/2)
                    index = i;
                    break;
                end
            end
            med = a(index) + h*(((n+1)/2 - app.N(index-1))/app.ni(index));

            % variacne
            variacne = R;
            
            %priemerna odchylka
            priemerna = (1/n)*sum(app.ni.*abs(x-priemer));
            
            %rozptyl
            rozptyl = (1/n)*(app.ni'*((x-priemer).^2));
            
            %smerodajna odchylka
            smerodajna = sqrt(rozptyl);
            
            %centralne momenty
            Mi3 = (1/n)*sum(app.ni.*(x-priemer).^3);
            Mi4 = (1/n)*(app.ni'*((x-priemer).^4));
            
            %koeficient sikmosti
            gama3 = Mi3/smerodajna^3;
            
            %koeficient spicatosti (excesu)
            gama4 = (Mi4/smerodajna^4)-3;

            r = {};
            r(end + 1) = {['Aritmetický priemer:_', num2str(priemer)]};
            r(end + 1) = {['Modus:_', num2str(modus)]};
            r(end + 1) = {['Medián:_', num2str(med)]};
            r(end + 1) = {['Variačné rozpätie:_', num2str(variacne)]};
            r(end + 1) = {['Priemerná odchýlka:_', num2str(priemerna)]};
            r(end + 1) = {['Rozptyl:_', num2str(rozptyl)]};
            r(end + 1) = {['Smerodajná odchýlka:_', num2str(smerodajna)]};
            r(end + 1) = {['Koeficient šikmosti:_', num2str(gama3)]};
            r(end + 1) = {['Koeficient špicatosti:_', num2str(gama4)]};
            
            app.AllResults = r;
            storeResults3(app, id);
        end

        function fillHeader(app, id)
            Fpath = fullfile(app.selectedPath, app.selectedFile);
            sheets = sheetnames(Fpath);
            
            if id == 1 || id == 2 || id == 3
                xlswrite(Fpath, {'Marcincakova'}, sheets(id + 4), 'D2');
                xlswrite(Fpath, {'Katarina'}, sheets(id + 4), 'D3');
                xlswrite(Fpath, {'A7'}, sheets(id + 4), 'D4');
            end
        end

        function storeResults3(app, id)
            fillHeader(app, id);

            Fpath = fullfile(app.selectedPath, app.selectedFile);
            disp(Fpath);
            sheets = sheetnames(Fpath);
            r = app.AllResults;
            if id == 1 || id == 2 || id == 3
                sheet = sheets(id + 4);
                for i=1:length(r)
                    pos = i + 7;
                    rs = split(r(i), '_');
                    xlswrite(Fpath, rs(1), sheet, ['K', num2str(pos)]);
                    xlswrite(Fpath, rs(2), sheet, ['N', num2str(pos)]);
                end
                fillTitle(app, id);
                storeTable(app, Fpath, sheet);

                if length(app.I) > 1
                    storeGraphs(app, Fpath, sheet);
                end
            end
        end

        function fillTitle(app, id)
            Fpath = fullfile(app.selectedPath, app.selectedFile);
            sheets = sheetnames(Fpath);
            sheet = sheets(id + 4);

            xlswrite(Fpath, {'i'}, sheet, 'B7');
            xlswrite(Fpath, {'a(i)'}, sheet, 'C7');
            xlswrite(Fpath, {'b(i)'}, sheet, 'D7');
            xlswrite(Fpath, {'x(i)'}, sheet, 'E7');
            xlswrite(Fpath, {'n(i)'}, sheet, 'F7');
            xlswrite(Fpath, {'N'}, sheet, 'G7');
            xlswrite(Fpath, {'f(i)'}, sheet, 'H7');
            xlswrite(Fpath, {'F'}, sheet, 'I7');
        end
    
        function storeTable(app, Fpath, sheet)

                for i=1:length(app.I)
                    pos = app.I(i) + 7;
                    xlswrite(Fpath, app.I(i), sheet, ['B', num2str(pos)]);
                    xlswrite(Fpath, app.ai(i), sheet, ['C', num2str(pos)]);
                    xlswrite(Fpath, app.bi(i), sheet, ['D', num2str(pos)]);
                    xlswrite(Fpath, app.xi(i), sheet, ['E', num2str(pos)]);
                    xlswrite(Fpath, app.ni(i), sheet, ['F', num2str(pos)]);
                    xlswrite(Fpath, app.N(i), sheet, ['G', num2str(pos)]);
                    xlswrite(Fpath, app.fi(i), sheet, ['H', num2str(pos)]);
                    xlswrite(Fpath, app.F(i), sheet, ['I', num2str(pos)]);
                end
        end
    
        function storeGraphs(app, Fpath, sheet)
            plot(app.ni, app.fi, LineStyle="-", Color="blue");
            title('Znázornenie vzťahu absolútnej triednej početnosti a relatívnej triednej početnosti');
            xlabel('Absolútna triedna početnosť (ni)');
            ylabel('Relatívna triedna početnosť (fi)');
            xlswritefig(app, '', fullfile(pwd, Fpath), sheet, 'S8');

            plot(app.xi, app.fi, LineStyle="-", Color="blue");
            title('Znázornenie vzťahu stredov tried a relatívnej triednej početnosti');
            xlabel('Stredy tried (xi)');
            ylabel('Relatívna triedna početnosť (fi)');
            xlswritefig(app, '', fullfile(pwd, Fpath), sheet, 'B43');

            plot(app.xi, app.F, LineStyle="-", Color="blue");
            title('Znázornenie vzťahu stredov tried a relatívnej kumulatívnej početnosti');
            xlabel('Stredy tried (xi)');
            ylabel('Relatívna kumulatívna početnosť (Fi)');
            xlswritefig(app, '', fullfile(pwd, Fpath), sheet, 'K43');

            plot(app.xi, app.ni, LineStyle="-", Color="blue");
            title('Znázornenie vzťahu stredov tried a absolútnej triednej početnosti');
            xlabel('Stredy tried (xi)');
            ylabel('Absolútna triedna početnosť (ni)');
            xlswritefig(app, '', fullfile(pwd, Fpath), sheet, 'S43');

            plot(app.xi, app.N, LineStyle="-", Color="blue");
            title('Znázornenie vzťahu stredov tried a absolútnej kumulatívnej početnosti');
            xlabel('Stredy tried (xi)');
            ylabel('Absolútna kumulatívna početnosť (Ni)');
            xlswritefig(app, '', fullfile(pwd, Fpath), sheet, 'B70');
        end

        function xlswritefig(app, hFig,filename,sheetname,xlcell)
            % XLSWRITEFIG  Write a MATLAB figure to an Excel spreadsheet
            %
            % xlswritefig(hFig,filename,sheetname,xlcell)
            %
            % All inputs are optional:
            %
            %    hFig:      Handle to MATLAB figure.  If empty, current figure is
            %                   exported
            %    filename   (string) Name of Excel file, including extension.  If not specified, contents will
            %                  be opened in a new Excel spreadsheet. 
            %    sheetname:  Name of sheet to write data to. The default is 'Sheet1'
            %                       If specified, a sheet with the specified name must
            %                       exist
            %    xlcell:     Designation of cell to indicate the upper-left corner of
            %                  the figure (e.g. 'D2').  Default = 'A1'
            %
            % Requirements: Must have Microsoft Excel installed.  Microsoft Windows
            % only.
            %
            % Ex:
            % Paste the current figure into a new Excel spreadsheet which is left open.
            %         plot(rand(10,1))
            %         drawnow    % Maybe overkill, but ensures plot is drawn first
            %         xlswritefig
            %
            % Specify all options.  
            %         hFig = figure;      
            %         surf(peaks)
            %         xlswritefig(hFig,'MyNewFile.xlsx','Sheet2','D4')
            %         winopen('MyNewFile.xlsx')   
            
            % Michelle Hirsch
            % The MathWorks
            % mhirsch@mathworks.com
            %
            % Is this function useful?  Drop me a line to let me know!
            
            
            if nargin==0 || isempty(hFig)
                hFig = gcf;
            end
            
            if nargin<2 || isempty(filename)
                filename ='';
                dontsave = true;
            else
                dontsave = false;
                
                % Create full file name with path
                filename = fullfilename(app, filename);
            end
            
            if nargin < 3 || isempty(sheetname)
                sheetname = 'Sheet1';
            end
            
            if nargin<4
                xlcell = 'A1';
            end
            
            
            % Put figure in clipboard
            if ~verLessThan('matlab','9.8')
                copygraphics(hFig)
            else
                % For older releases, use hgexport. Set renderer to painters to make
                % sure it looks right.
                r = get(hFig,'Renderer');
                set(hFig,'Renderer','Painters')
                drawnow
                hgexport(hFig,'-clipboard')
                set(hFig,'Renderer',r)
            end
            
            
            % Open Excel, add workbook, change active worksheet,
            % get/put array, save.
            % First, open an Excel Server.
            Excel = actxserver('Excel.Application');
            
            % Two cases:
            % * Open a new workbook, save with given file name
            % * Open an existing workbook
            
            if exist(filename,'file')==0
                % The following case if file does not exist (Creating New File)
                op = invoke(Excel.Workbooks,'Add');
                %     invoke(op, 'SaveAs', [pwd filesep filename]);
                new=1;
            else
                % The following case if file does exist (Opening File)
                %     disp(['Opening Excel File ...(' filename ')']);
                op = invoke(Excel.Workbooks, 'open', filename);
                new=0;
            end
            
            % set(Excel, 'Visible', 0);
            
            % Make the specified sheet active.
            try
                Sheets = Excel.ActiveWorkBook.Sheets;
                target_sheet = get(Sheets, 'Item', sheetname);
            catch %#ok<CTCH>   Suppress so that this function works in releases without MException
                % Add the sheet if it doesn't exist
                target_sheet = Excel.ActiveWorkBook.Worksheets.Add();
                target_sheet.Name = sheetname;
            end
            
            invoke(target_sheet, 'Activate');
            Activesheet = Excel.Activesheet;
            
            
            % Paste to specified cell
            Paste(Activesheet,get(Activesheet,'Range',xlcell,xlcell))
            
            % Save and clean up
            if new && ~dontsave
                invoke(op, 'SaveAs', filename);
            elseif ~new
                invoke(op, 'Save');
            else  % New, but don't save
                set(Excel, 'Visible', 1);
                return  % Bail out before quitting Excel
            end
            invoke(Excel, 'Quit');
            delete(Excel)
        end

        function filename = fullfilename(app, filename)
            [filepath, filename, fileext] = fileparts(filename);
            if isempty(filepath)
                filepath = pwd;
            end
            if isempty(fileext)
                fileext = '.xlsx';
            end
            filename = fullfile(filepath, [filename fileext]);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: numberOfColors
        function numberOfColorsValueChanged(app, event)
            updateListboxWithColors(app);
        end

        % Button pushed function: AktualizovaButton
        function AktualizovaButtonPushed(app, event)
            values = app.PomerfariebEditField.Value;
            splitedValues = strsplit(values);

            [~,numberOfValues] = size(splitedValues);
            
            splitedValuesInt = [];
            
            newBallCount = 0;
            inputBallCount = app.numberOfBalls.Value;

            colorsCount = app.numberOfColors.Value;

            for i = 1:min(numberOfValues, colorsCount)
                currentValue = str2double(splitedValues(i));
                if isnan(currentValue)
                    uialert(app.UIFigure,'Medzi zadanými hodnotami sa nachádzajú aj iné znaky ako čísla, prípadne medzera na konci navyše','Chyba');
                    return
                end
                splitedValuesInt(end+1) = currentValue;
                newBallCount = newBallCount + currentValue;
            end

            if newBallCount < inputBallCount && numberOfValues >= colorsCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je menší ako počet guličiek.','Chyba');
                return
            end 

            if newBallCount > inputBallCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je väčší ako počet guličiek.','Chyba');
                return
            end

            if numberOfValues > colorsCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je vyšší ako počet farieb. Hodnoty navyše budú zahodené','Varovanie', 'Icon', 'warning');
            end

            countOneColor = 0;
            m = 0;

            if numberOfValues < colorsCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je menší ako počet farieb. Zvyšný diel bude rovnomerne rozdelený medzi zvyšné farby','Varovanie', 'Icon', 'warning');
                
                missingBalls = inputBallCount - newBallCount;
                missingColors = colorsCount - numberOfValues;
                countOneColor = floor(missingBalls / missingColors);
                m = mod(missingBalls, missingColors);
            end

            app.ZtohojeListBox.Items = {};
            drawnow
            
            middleStop = min(numberOfValues, colorsCount) + 1;
            for i=middleStop:colorsCount
                if i == middleStop
                    splitedValuesInt(end+1) = m + countOneColor;
                else
                    splitedValuesInt(end+1) = countOneColor;
                end

            end

            for i=1:colorsCount
                name = ['farba ', num2str(i), ': ', num2str(splitedValuesInt(i)), ' ks'];
                app.ZtohojeListBox.Items(end+1) = {name};
                drawnow
            end
        end

        % Button pushed function: NastavihodnotyzozadaniaButton
        function NastavihodnotyzozadaniaButtonPushed(app, event)
            app.numberOfBalls.Value = 150;
            app.numberOfColors.Value = 2;
            app.ZtohojeListBox.Items = {};
            drawnow
            app.ZtohojeListBox.Items(end+1) = {'farba 1: 50 ks'};
            app.ZtohojeListBox.Items(end+1) = {'farba 2: 100 ks'};
            drawnow
            setPickedToRozne(app);
            app.numberOfPicked.Value = 2;
            app.PomerfariebEditField_2.Value = '1 1';
        end

        % Button pushed function: VypotapravdepodobnosButton
        function VypotapravdepodobnosButtonPushed(app, event)
            app.NumberOfAllBalls = app.numberOfBalls.Value;
            app.NumberOfColors = app.numberOfColors.Value;
            app.NumberOfPickedBalls = app.numberOfPicked.Value;
            getBallsInPack(app);

            if app.rzneButton.Value == false && app.rovnakButton.Value == true
                pickedRozne = false;
            elseif app.rzneButton.Value == true && app.rovnakButton.Value == false
                pickedRozne = true;
            else
                uialert(app.UIFigure,'Chyba pri zadávaní toho či sa vyberú rovnaké alebo rôzne guličky.','Chyba');
                return
            end

            if pickedRozne == true
                pickedDifferentBalls(app);
            else
                pickedSameBalls(app);
            end
        end

        % Button pushed function: OtvoritsborsvsledkamiButton
        function OtvoritsborsvsledkamiButtonPushed(app, event)
            !notepad OutputFiles\Pravdepodobnost1.txt
        end

        % Value changed function: numberOfBalls
        function numberOfBallsValueChanged(app, event)
            updateListboxWithColors(app);
            m = app.numberOfBalls.Value;
            if app.numberOfColors.Value >= m
                app.numberOfColors.Value = m;
            end
            if app.numberOfPicked.Value >= m
                app.numberOfPicked.Value = m;
            end
            app.numberOfColors.Limits = [1, m];
            app.numberOfPicked.Limits = [1, m];
        end

        % Button pushed function: OtvoritprieinoksvsledkamiButton, 
        % OtvoritprieinoksvsledkamiButton_2
        function OtvoritprieinoksvsledkamiButtonPushed(app, event)
            !start OutputFiles\
        end

        % Button pushed function: NastavihodnotyzozadaniaButton_2
        function NastavihodnotyzozadaniaButton_2Pushed(app, event)
             app.first.Value = '6-sten';
             app.second.Value = '12-sten';
             app.numberOfRolls.Value = 10;
             app.numberOfSite3.Value = 4;
        end

        % Button pushed function: OtvoritsborsvsledkamiButton_2
        function OtvoritsborsvsledkamiButton_2Pushed(app, event)
            !notepad OutputFiles\Pravdepodobnost2.txt
        end

        % Value changed function: numberOfRolls
        function numberOfRollsValueChanged(app, event)
            value = app.numberOfRolls.Value;
            if app.numberOfSite3 > value
                app.numberOfSite3.Value = value;
            end
            app.numberOfSite3.Limits = [1, value];
        end

        % Value changed function: first
        function firstValueChanged(app, event)
            value = app.first.Value;
            splited = strsplit(value, '-');
            n = str2double(splited(1));
            secondValue = app.second.Value;
            splited = strsplit(secondValue, '-');
            sV = str2double(splited(1));

            listOfobjects = app.second.Items;

            app.second.Items = {};
            drawnow

            [~,numberOfValues] = size(listOfobjects);

            for i=1:numberOfValues
                splited = split(listOfobjects(i), '-');
                x = str2double(splited(1));
                if x >= n
                    app.second.Items(end+1) = listOfobjects(i);
                    drawnow
                end
            end

            if sV >= n
                app.second.Value = secondValue;
            end
        end

        % Value changed function: second
        function secondValueChanged(app, event)
            value = app.second.Value;
            splited = strsplit(value, '-');
            n = str2double(splited(1));
            firstValue = app.first.Value;
            splited = strsplit(firstValue, '-');
            fV = str2double(splited(1));

            listOfobjects = app.first.Items;

            app.first.Items = {};
            drawnow

            [~,numberOfValues] = size(listOfobjects);

            for i=1:numberOfValues
                splited = split(listOfobjects(i), '-');
                x = str2double(splited(1));
                if x <= n
                    app.first.Items(end+1) = listOfobjects(i);
                    drawnow
                end
            end

            if fV <= n
                app.first.Value = firstValue;
            end
        end

        % Button pushed function: VypotaminimlnypoethodovButton
        function VypotaminimlnypoethodovButtonPushed(app, event)
            value = app.first.Value;
            splited = strsplit(value, '-');
            app.firstValue = str2double(splited(1));

            value = app.second.Value;
            splited = strsplit(value, '-');
            app.secondValue = str2double(splited(1));

            app.allRolls = app.numberOfRolls.Value;
            app.successRolls = app.numberOfSite3.Value;

            probabilityFirst(app);

            countRolls(app);

            app.results2.Value = ['Druhou kockou musíme hodiť minimálne ', num2str(app.pocetHodov), '-krát aby pravdepodobnosť padnutia čísla 3 bola vyššia ako v prípade hádzania prvou kockou'];

            storeResults2(app);
        end

        % Button down function: loha3Tab
        function loha3TabButtonDown(app, event)
             app.selectedFile = ('PopisnaStatistika-10.xlsx');
             app.selectedPath = ('InputFiles');
             loadMetaData(app);
        end

        % Button pushed function: VybraButton
        function VybraButtonPushed(app, event)
            [file,path] =  uigetfile('*.xlsx', 'Vyber vstupný súbor');
            if isequal(file,0) 
               
            else
                app.selectedPath = path;
                app.selectedFile = file;
                disp(app.selectedPath);
                disp(app.selectedFile);
                loadMetaData(app);
            end

            app.fileName.Value = app.selectedFile;
        end

        % Button pushed function: NastavihodnotyzozadaniaButton_3
        function NastavihodnotyzozadaniaButton_3Pushed(app, event)
            app.selectedFile = ('PopisnaStatistika-10.xlsx');
            app.selectedPath = ('InputFiles');
            app.fileName.Value = app.selectedFile;
                disp(app.selectedPath);
                disp(app.selectedFile);
        end

        % Button pushed function: Spustianalzudt1Button
        function Spustianalzudt1ButtonPushed(app, event)
            app.results3.Value = 'Analyzujem...';
            drawnow
            if length(app.Data1) <= 30
                calculateUnder30(app, app.Data1, 1);
            else
                calculateOver30(app, app.Data1, 1);
            end
            app.results3.Value = 'Dáta 1 boli úspešne zanalyzované a výsledky uložené do súboru.';
        end

        % Button pushed function: OtvoritsborsvsledkamiButton_3
        function OtvoritsborsvsledkamiButton_3Pushed(app, event)
            winopen(fullfile(app.selectedPath, app.selectedFile));
        end

        % Button pushed function: OtvoritprieinoksvsledkamiButton_3
        function OtvoritprieinoksvsledkamiButton_3Pushed(app, event)
            winopen(app.selectedPath);
        end

        % Button pushed function: Spustianalzudt2Button
        function Spustianalzudt2ButtonPushed(app, event)
            app.results3.Value = 'Analyzujem...';
            drawnow
            if length(app.Data2) <= 30
                calculateUnder30(app, app.Data2, 2);
            else
                calculateOver30(app, app.Data2, 2);
            end
            app.results3.Value = 'Dáta 2 boli úspešne zanalyzované a výsledky uložené do súboru.';
        end

        % Button pushed function: Spustianalzudt3Button
        function Spustianalzudt3ButtonPushed(app, event)
            app.results3.Value = 'Analyzujem...';
            drawnow
            if length(app.Data3) <= 30
                calculateUnder30(app, app.Data3, 3);
            else
                calculateOver30(app, app.Data3, 3);
            end
            app.results3.Value = 'Dáta 3 boli úspešne zanalyzované a výsledky uložené do súboru.';
        end

        % Button pushed function: SpustianalzuvtekchdtButton
        function SpustianalzuvtekchdtButtonPushed(app, event)
            app.results3.Value = 'Analyzujem...';
            drawnow
            Spustianalzudt1ButtonPushed(app, event);
            Spustianalzudt2ButtonPushed(app, event);
            Spustianalzudt3ButtonPushed(app, event);
            app.results3.Value = 'Všetky dáta boli úspešne zanalyzované a výsledky uložené do súboru.';
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.loha1Tab = uitab(app.TabGroup);
            app.loha1Tab.Title = 'Úloha 1';
            app.ZadanieLabel = uilabel(app.loha1Tab);
            app.ZadanieLabel.Text = 'Zadanie';
            app.PoetguliiekvurneLabel = uilabel(app.loha1Tab);
            app.PoetguliiekvurneLabel.Text = 'Počet guličiek v urne';
            app.numberOfBalls = uispinner(app.loha1Tab);
            app.numberOfBalls.Limits = [1 Inf];
            app.numberOfBalls.ValueChangedFcn = createCallbackFcn(app, @numberOfBallsValueChanged, true);
            app.numberOfBalls.Value = 100;
            app.PoetfariebguliiekLabel = uilabel(app.loha1Tab);
            app.PoetfariebguliiekLabel.Text = 'Počet farieb guličiek';
            app.numberOfColors = uispinner(app.loha1Tab);
            app.numberOfColors.Limits = [1 100];
            app.numberOfColors.ValueChangedFcn = createCallbackFcn(app, @numberOfColorsValueChanged, true);
            app.numberOfColors.Value = 1;
            app.VytiahnutgulikybudButtonGroup = uibuttongroup(app.loha1Tab);
            app.VytiahnutgulikybudButtonGroup.Title = 'Vytiahnuté guličky budú';
            app.rovnakButton = uiradiobutton(app.VytiahnutgulikybudButtonGroup);
            app.rovnakButton.Text = 'rovnaké';
            app.rovnakButton.Value = true;
            app.rzneButton = uiradiobutton(app.VytiahnutgulikybudButtonGroup);
            app.rzneButton.Text = 'rôzne';
            app.VytiahnemeksLabel = uilabel(app.loha1Tab);
            app.VytiahnemeksLabel.Text = 'Vytiahneme                          ks.';
            app.numberOfPicked = uispinner(app.loha1Tab);
            app.numberOfPicked.Limits = [1 100];
            app.numberOfPicked.Value = 1;
            app.VypotapravdepodobnosButton = uibutton(app.loha1Tab, 'push');
            app.VypotapravdepodobnosButton.ButtonPushedFcn = createCallbackFcn(app, @VypotapravdepodobnosButtonPushed, true);
            app.VypotapravdepodobnosButton.Text = 'Vypočítať pravdepodobnosť';
            app.NastavihodnotyzozadaniaButton = uibutton(app.loha1Tab, 'push');
            app.NastavihodnotyzozadaniaButton.ButtonPushedFcn = createCallbackFcn(app, @NastavihodnotyzozadaniaButtonPushed, true);
            app.NastavihodnotyzozadaniaButton.Text = 'Nastaviť hodnoty zo zadania';
            app.VsledkyLabel = uilabel(app.loha1Tab);
            app.VsledkyLabel.Text = 'Výsledky';
            app.ZtohojeListBoxLabel = uilabel(app.loha1Tab);
            app.ZtohojeListBoxLabel.Text = 'Z toho je';
            app.ZtohojeListBox = uilistbox(app.loha1Tab);
            app.ZtohojeListBox.Items = {'farba 1: 100 ks'};
            app.ZtohojeListBox.Value = 'farba 1: 100 ks';
            app.PomerfariebEditFieldLabel = uilabel(app.loha1Tab);
            app.PomerfariebEditFieldLabel.Text = 'Pomer farieb';
            app.PomerfariebEditField = uieditfield(app.loha1Tab, 'text');
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel = uilabel(app.loha1Tab);
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel.Text = 'Pomer farieb zadávajte ako celé čísla oddelené medzerou. Poradie čísla na vstupe spadá pod farbu s daným číslom. Ak súčet hodnôt na vstupe bude menší ako celkový počet guličiek, chýbajúca časť sa rovnomerne rozdelí medzi guličky ktorým nebolo priradený pomer.';
            app.AktualizovaButton = uibutton(app.loha1Tab, 'push');
            app.AktualizovaButton.ButtonPushedFcn = createCallbackFcn(app, @AktualizovaButtonPushed, true);
            app.AktualizovaButton.Text = 'Aktualizovať';
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel_2 = uilabel(app.loha1Tab);
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel_2.Text = 'V prípade, že bola zvolená možnosť ,,rôzne" napíšte, koľko z ktorej farby sa vytiahne. Princíp zapisovania je orvnaký ako pri nastavovaní pomeru farieb v urne.';
            app.PomerfariebEditField_2Label = uilabel(app.loha1Tab);
            app.PomerfariebEditField_2Label.Text = 'Pomer farieb';
            app.PomerfariebEditField_2 = uieditfield(app.loha1Tab, 'text');
            app.PomerfariebEditField_2.Value = '1';
            app.Results = uilistbox(app.loha1Tab);
            app.Results.Items = {};
            app.Results.Value = {};
            app.OtvoritsborsvsledkamiButton = uibutton(app.loha1Tab, 'push');
            app.OtvoritsborsvsledkamiButton.ButtonPushedFcn = createCallbackFcn(app, @OtvoritsborsvsledkamiButtonPushed, true);
            app.OtvoritsborsvsledkamiButton.Text = 'Otvorit súbor s výsledkami';
            app.OtvoritprieinoksvsledkamiButton = uibutton(app.loha1Tab, 'push');
            app.OtvoritprieinoksvsledkamiButton.ButtonPushedFcn = createCallbackFcn(app, @OtvoritprieinoksvsledkamiButtonPushed, true);
            app.OtvoritprieinoksvsledkamiButton.Text = 'Otvorit priečinok s výsledkami';
            app.loha2Tab = uitab(app.TabGroup);
            app.loha2Tab.Title = 'Úloha 2';
            app.first = uidropdown(app.loha2Tab);
            app.first.Items = {'4-sten', '6-sten', '8-sten', '12-sten'};
            app.first.ValueChangedFcn = createCallbackFcn(app, @firstValueChanged, true);
            app.first.Value = '4-sten';
            app.ZadanieLabel_2 = uilabel(app.loha2Tab);
            app.ZadanieLabel_2.Text = 'Zadanie';
            app.KockaktorousahdeprvouLabel = uilabel(app.loha2Tab);
            app.KockaktorousahdeprvouLabel.Text = 'Kocka ktorou sa hádže prvou';
            app.KockaktorousahdedruhouLabel = uilabel(app.loha2Tab);
            app.KockaktorousahdedruhouLabel.Text = 'Kocka ktorou sa hádže druhou';
            app.second = uidropdown(app.loha2Tab);
            app.second.Items = {'6-sten', '8-sten', '12-sten', '20-sten'};
            app.second.ValueChangedFcn = createCallbackFcn(app, @secondValueChanged, true);
            app.second.Value = '6-sten';
            app.PoethodovprvoukockouLabel = uilabel(app.loha2Tab);
            app.PoethodovprvoukockouLabel.Text = 'Počet hodov prvou kockou';
            app.numberOfRolls = uispinner(app.loha2Tab);
            app.numberOfRolls.Limits = [1 Inf];
            app.numberOfRolls.ValueChangedFcn = createCallbackFcn(app, @numberOfRollsValueChanged, true);
            app.numberOfRolls.Value = 10;
            app.Poetpadnutstranyslo3Label = uilabel(app.loha2Tab);
            app.Poetpadnutstranyslo3Label.Text = 'Počet padnutí strany číslo 3';
            app.numberOfSite3 = uispinner(app.loha2Tab);
            app.numberOfSite3.Limits = [1 10];
            app.numberOfSite3.Value = 1;
            app.VypotaminimlnypoethodovButton = uibutton(app.loha2Tab, 'push');
            app.VypotaminimlnypoethodovButton.ButtonPushedFcn = createCallbackFcn(app, @VypotaminimlnypoethodovButtonPushed, true);
            app.VypotaminimlnypoethodovButton.Text = 'Vypočítať minimálny počet hodov';
            app.NastavihodnotyzozadaniaButton_2 = uibutton(app.loha2Tab, 'push');
            app.NastavihodnotyzozadaniaButton_2.ButtonPushedFcn = createCallbackFcn(app, @NastavihodnotyzozadaniaButton_2Pushed, true);
            app.NastavihodnotyzozadaniaButton_2.Text = 'Nastaviť hodnoty zo zadania';
            app.VsledkyLabel_2 = uilabel(app.loha2Tab);
            app.VsledkyLabel_2.Text = 'Výsledky';
            app.OtvoritsborsvsledkamiButton_2 = uibutton(app.loha2Tab, 'push');
            app.OtvoritsborsvsledkamiButton_2.ButtonPushedFcn = createCallbackFcn(app, @OtvoritsborsvsledkamiButton_2Pushed, true);
            app.OtvoritsborsvsledkamiButton_2.Text = 'Otvorit súbor s výsledkami';
            app.OtvoritprieinoksvsledkamiButton_2 = uibutton(app.loha2Tab, 'push');
            app.OtvoritprieinoksvsledkamiButton_2.ButtonPushedFcn = createCallbackFcn(app, @OtvoritprieinoksvsledkamiButtonPushed, true);
            app.OtvoritprieinoksvsledkamiButton_2.Text = 'Otvorit priečinok s výsledkami';
            app.Label = uilabel(app.loha2Tab);
            app.Label.Text = '';
            app.results2 = uitextarea(app.loha2Tab);
            app.loha3Tab = uitab(app.TabGroup);
            app.loha3Tab.Title = 'Úloha 3';
            app.loha3Tab.ButtonDownFcn = createCallbackFcn(app, @loha3TabButtonDown, true);
            app.ZadanieLabel_3 = uilabel(app.loha3Tab);
            app.ZadanieLabel_3.Text = 'Zadanie';
            app.fileName = uieditfield(app.loha3Tab, 'text');
            app.fileName.Editable = 'off';
            app.fileName.Value = 'PopisnaStatistika-10.xlsx';
            app.VstupnsborLabel = uilabel(app.loha3Tab);
            app.VstupnsborLabel.Text = 'Vstupný súbor';
            app.VybraButton = uibutton(app.loha3Tab, 'push');
            app.VybraButton.ButtonPushedFcn = createCallbackFcn(app, @VybraButtonPushed, true);
            app.VybraButton.Text = 'Vybrať';
            app.numberOfClasses1 = uispinner(app.loha3Tab);
            app.numberOfClasses1.Limits = [1 Inf];
            app.numberOfClasses1.Tooltip = {'V prípade, že políčko je šedé, znamená to, že je príliš málo vstupných dát a vytváranie tried nie je potrebné.'};
            app.numberOfClasses1.Value = 1;
            app.PoettriedpoetnostiLabel = uilabel(app.loha3Tab);
            app.PoettriedpoetnostiLabel.Text = 'Počet tried početnosti';
            app.DolnhranicaprvejtriedyLabel = uilabel(app.loha3Tab);
            app.DolnhranicaprvejtriedyLabel.Text = 'Dolná hranica prvej triedy';
            app.bottomLine1 = uispinner(app.loha3Tab);
            app.bottomLine1.Tooltip = {'V prípade, že políčko je šedé, znamená to, že je príliš málo vstupných dát a vytváranie tried nie je potrebné.'};
            app.SpustianalzuvtekchdtButton = uibutton(app.loha3Tab, 'push');
            app.SpustianalzuvtekchdtButton.ButtonPushedFcn = createCallbackFcn(app, @SpustianalzuvtekchdtButtonPushed, true);
            app.SpustianalzuvtekchdtButton.Text = 'Spustiť analýzu vštekých dát';
            app.NastavihodnotyzozadaniaButton_3 = uibutton(app.loha3Tab, 'push');
            app.NastavihodnotyzozadaniaButton_3.ButtonPushedFcn = createCallbackFcn(app, @NastavihodnotyzozadaniaButton_3Pushed, true);
            app.NastavihodnotyzozadaniaButton_3.Text = 'Nastaviť hodnoty zo zadania';
            app.Spustianalzudt1Button = uibutton(app.loha3Tab, 'push');
            app.Spustianalzudt1Button.ButtonPushedFcn = createCallbackFcn(app, @Spustianalzudt1ButtonPushed, true);
            app.Spustianalzudt1Button.Text = 'Spustiť analýzu dát 1';
            app.Vstupndta1Label = uilabel(app.loha3Tab);
            app.Vstupndta1Label.Text = 'Vstupné dáta 1';
            app.numberOfClasses2 = uispinner(app.loha3Tab);
            app.numberOfClasses2.Limits = [1 Inf];
            app.numberOfClasses2.Tooltip = {'V prípade, že políčko je šedé, znamená to, že je príliš málo vstupných dát a vytváranie tried nie je potrebné.'};
            app.numberOfClasses2.Value = 1;
            app.PoettriedpoetnostiLabel_2 = uilabel(app.loha3Tab);
            app.PoettriedpoetnostiLabel_2.Text = 'Počet tried početnosti';
            app.DolnhranicaprvejtriedyLabel_2 = uilabel(app.loha3Tab);
            app.DolnhranicaprvejtriedyLabel_2.Text = 'Dolná hranica prvej triedy';
            app.bottomLine2 = uispinner(app.loha3Tab);
            app.bottomLine2.Tooltip = {'V prípade, že políčko je šedé, znamená to, že je príliš málo vstupných dát a vytváranie tried nie je potrebné.'};
            app.Spustianalzudt2Button = uibutton(app.loha3Tab, 'push');
            app.Spustianalzudt2Button.ButtonPushedFcn = createCallbackFcn(app, @Spustianalzudt2ButtonPushed, true);
            app.Spustianalzudt2Button.Text = 'Spustiť analýzu dát 2';
            app.Vstupndta2Label = uilabel(app.loha3Tab);
            app.Vstupndta2Label.Text = 'Vstupné dáta 2';
            app.numberOfClasses3 = uispinner(app.loha3Tab);
            app.numberOfClasses3.Limits = [1 Inf];
            app.numberOfClasses3.Tooltip = {'V prípade, že políčko je šedé, znamená to, že je príliš málo vstupných dát a vytváranie tried nie je potrebné.'};
            app.numberOfClasses3.Value = 1;
            app.PoettriedpoetnostiLabel_3 = uilabel(app.loha3Tab);
            app.PoettriedpoetnostiLabel_3.Text = 'Počet tried početnosti';
            app.DolnhranicaprvejtriedyLabel_3 = uilabel(app.loha3Tab);
            app.DolnhranicaprvejtriedyLabel_3.Text = 'Dolná hranica prvej triedy';
            app.bottomLine3 = uispinner(app.loha3Tab);
            app.bottomLine3.Tooltip = {'V prípade, že políčko je šedé, znamená to, že je príliš málo vstupných dát a vytváranie tried nie je potrebné.'};
            app.Spustianalzudt3Button = uibutton(app.loha3Tab, 'push');
            app.Spustianalzudt3Button.ButtonPushedFcn = createCallbackFcn(app, @Spustianalzudt3ButtonPushed, true);
            app.Spustianalzudt3Button.Text = 'Spustiť analýzu dát 3';
            app.Vstupndta3Label = uilabel(app.loha3Tab);
            app.Vstupndta3Label.Text = 'Vstupné dáta 3';
            app.VsledkyLabel_3 = uilabel(app.loha3Tab);
            app.VsledkyLabel_3.Text = 'Výsledky';
            app.OtvoritsborsvsledkamiButton_3 = uibutton(app.loha3Tab, 'push');
            app.OtvoritsborsvsledkamiButton_3.ButtonPushedFcn = createCallbackFcn(app, @OtvoritsborsvsledkamiButton_3Pushed, true);
            app.OtvoritsborsvsledkamiButton_3.Text = 'Otvorit súbor s výsledkami';
            app.OtvoritprieinoksvsledkamiButton_3 = uibutton(app.loha3Tab, 'push');
            app.OtvoritprieinoksvsledkamiButton_3.ButtonPushedFcn = createCallbackFcn(app, @OtvoritprieinoksvsledkamiButton_3Pushed, true);
            app.OtvoritprieinoksvsledkamiButton_3.Text = 'Otvorit priečinok s výsledkami';
            app.results3 = uitextarea(app.loha3Tab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zadanie3App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end